classdef lsmApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ResetButton                    matlab.ui.control.Button
        LoadButton                     matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        phi1TextArea                   matlab.ui.control.TextArea
        phi_1theta_writethetaassymbolitthiLabel  matlab.ui.control.Label
        phi0TextArea                   matlab.ui.control.TextArea
        phi_0theta_writethetaassymbolitthiTextAreaLabel  matlab.ui.control.Label
        HelpButton                     matlab.ui.control.Button
        alphaEitField                  matlab.ui.control.EditField
        alpha_6valuesvectorEditField_2Label  matlab.ui.control.Label
        thetaEditField                 matlab.ui.control.EditField
        theta_6valuesvectorEditField_2Label  matlab.ui.control.Label
        LeastSquareMethodfortwoparametriccaseLabel  matlab.ui.control.Label
        dtEditField                    matlab.ui.control.NumericEditField
        itdtrm_stepLabel               matlab.ui.control.Label
        th1EditField                   matlab.ui.control.NumericEditField
        theta_1_uppertimeboudaryLabel  matlab.ui.control.Label
        th0EditField                   matlab.ui.control.NumericEditField
        theta_0_lowertimeboudaryLabel  matlab.ui.control.Label
        b1EditField                    matlab.ui.control.NumericEditField
        b_1EditFieldLabel              matlab.ui.control.Label
        b0EditField                    matlab.ui.control.NumericEditField
        b_0EditFieldLabel              matlab.ui.control.Label
        ExitButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        lsmResultsApp; % application window to show LSM results
        helpApp; % help window
    end

    %% Least Square Method for calculating model parameters. Two parameter case
    methods (Access = private)

        %% --- function PrintArgsToFile
        % fileName, th, a, f0Str, f1Str must be char arrays
        function PrintArgsToFile(~, fileName, b0,b1,th,a, Th0,Th1,dt, f0Str, f1Str)

            % initFileID 3 means created, 4 means opened
            argsFileID = fopen(fileName, 'w+');
            
            try            
                fprintf(argsFileID, '%g;', b0);
                fprintf(argsFileID, '%g;', b1);
                fprintf(argsFileID, '%s;', th);
                fprintf(argsFileID, '%s;', a);
                fprintf(argsFileID, '%g;', Th0);
                fprintf(argsFileID, '%g;', Th1);
                fprintf(argsFileID, '%g;', dt);
                fprintf(argsFileID, '%s;', f0Str);
                fprintf(argsFileID, '%s;', f1Str);
            catch ME
                fclose(argsFileID);
                rethrow(ME);
            end

            fclose(argsFileID);
        end
        
        %% --- function ReadArgsFromFile
        function ReadArgsFromFile (app, fileName)
                 
                fileID = fopen(fileName, 'r+');

                args = textscan(fileID,'%s','delimiter',';');
                args = args{1};

                fclose(fileID);

                b0 = str2double(args(1));
                b1 = str2double(args(2));
                th = str2num(cell2mat(args(3)));
                a = str2num(cell2mat(args(4)));
                Th0 = str2double(args(5));
                Th1 = str2double(args(6));
                dt = str2double(args(7));
                % user text input of functions
                f0StrIn = cell2mat(args(8));
                f1StrIn = cell2mat(args(9));

                % convert theta and alpha arrays to string
                % 32 is space symbol
                thetaStr = num2str(th(1));
                for i = 2:length(th)
                    thetaStr = strcat(thetaStr, ',', 32, num2str(th(i)));
                end

                alphaStr = num2str(a(1));
                for i = 2:length(a)
                    alphaStr = strcat(alphaStr, ',', 32, num2str(a(i)));
                end

                app.b0EditField.Value = b0;
                app.b1EditField.Value = b1;
                app.thetaEditField.Value = thetaStr;
                app.alphaEitField.Value = alphaStr;
                app.th0EditField.Value = Th0;
                app.th1EditField.Value = Th1;
                app.dtEditField.Value = dt;
                app.phi0TextArea.Value = f0StrIn;
                app.phi1TextArea.Value = f1StrIn;
        end

        %% --- function Table2str
        % converts
        function str = Table2str(~, table)

            curStr = '';
            for i = 1:height(table)
                curStr = strcat(curStr,cell2mat(table{i,1}));
            end

            str = curStr;
        end
        
        %% --- function ExitApp
        function ExitApp(app)
            % save current inputs on exit
            b0 = app.b0EditField.Value;
            b1 = app.b1EditField.Value;
            th = app.thetaEditField.Value;
            a =  app.alphaEitField.Value;
            Th0 = app.th0EditField.Value;
            Th1 = app.th1EditField.Value;
            dt = app.dtEditField.Value;
            f0TableIn = cell2table(app.phi0TextArea.Value);
            f1TableIn = cell2table(app.phi1TextArea.Value);

            f0Str = Table2str(app, f0TableIn);
            f1Str = Table2str(app, f1TableIn);

            % Open or create initFile
            tmpFile = 'tmp.csv';

            % print to tmp.csv file
            PrintArgsToFile(app, tmpFile, b0, b1, th, a, Th0, Th1, dt, f0Str, f1Str);

            fclose('all');
            close all;
            delete(app.lsmResultsApp);
            delete(app.helpApp);
            delete(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fclose('all');
            clc;

            %% Init params by default values
            b0 = app.b0EditField.Value;
            b1 = app.b1EditField.Value;
            th = app.thetaEditField.Value;
            a =  app.alphaEitField.Value;
            Th0 = app.th0EditField.Value;
            Th1 = app.th1EditField.Value;
            dt = app.dtEditField.Value;
            % startup text input of functions
            f0TableIn = cell2table(app.phi0TextArea.Value);
            f1TableIn = cell2table(app.phi1TextArea.Value);

            % if several rows, conveting table to string
            % rows in table are rows of input textArea
            f0Str = Table2str(app, f0TableIn);
            f1Str = Table2str(app, f1TableIn);

            %% Open or create initFile

            tmpFile = 'tmp.csv';
            tmpFileID = 0;

            % Check file            
            % if file not exists, write default values to file
            if (isfile(tmpFile) == 0) 
                
                tmpFileID = fopen(tmpFile, 'w+');

                % print to tmp.csv file
                PrintArgsToFile(app, tmpFile, b0, b1, th, a, Th0, Th1, dt, f0Str, f1Str);

                fclose(tmpFileID);

            % if file exists, read values, then write new user values
            elseif (isfile(tmpFile) == 1)

                ReadArgsFromFile(app, tmpFile)

            else
                fclose(tmpFileID);
                error('Initfile error. Try to delete %s to set deffault values.', tmp.csv);
            end

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            ExitApp(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            ExitApp(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            delete(app.lsmResultsApp);
            close('all');

            %% Init params by fields values
            b0 = app.b0EditField.Value;
            b1 = app.b1EditField.Value;
            % will later converted to array
            %th = str2num(app.thetaEditField.Value)
            %a =  str2num(app.alphaEitField.Value)
            th = app.thetaEditField.Value;
            a =  app.alphaEitField.Value;
            Th0 = app.th0EditField.Value;
            Th1 = app.th1EditField.Value;
            dt = app.dtEditField.Value;
            % user text input of functions
            f0TableIn = cell2table(app.phi0TextArea.Value);
            f1TableIn = cell2table(app.phi1TextArea.Value);

            % if user wrote several rows, conveting table to string
            % rows in table are rows of input textArea
            f0Str = Table2str(app, f0TableIn);
            f1Str = Table2str(app, f1TableIn);

            %% Open or create initFile

            tmpFile = 'tmp.csv';

            % print to tmp.csv file
            PrintArgsToFile(app, tmpFile, b0, b1, th, a, Th0, Th1, dt, f0Str, f1Str)
            
            % convert to array
            th = str2num(app.thetaEditField.Value);
            a =  str2num(app.alphaEitField.Value);
            
           %% Convert text input of functions to LaTeX markup for plot naming
           % functions handlers are made from text
            
            ph0 = str2func(['@(thi) ', f0Str]);
            ph1 = str2func(['@(thi) ', f1Str]);
   
            phi0Str = latex(sym(ph0));
            phi1Str = latex(sym(ph1));

            % replace 'thi' to '\theta' in string. LaTeX markup is used in plot title
            %LaTeX markup is used. Do not use $ symbol at begin, end. It added later in
            %lsm function on plotTitle parsing. phi0Str, phi1Str sould be like:
            % phi0Str = 'e^{\theta}'
            % phi1Str = '\theta'
            phi0Str = replace(phi0Str, 'thi', '\theta');
            phi1Str = replace(phi1Str, 'thi', '\theta');

            %Lsm(app, b0, b1, th, a, ph0, ph1, phi0Str, phi1Str, Th0, Th1, dt);
            [c0, c1, G, b0e, b1e, ae, sig, sigB0e, sigB1e, b0eCI, b1eCI, sigAe, aeCI, aAve, sigA, n] = lsmFunc(b0, b1, th, a, ph0, ph1, phi0Str, phi1Str, Th0, Th1, dt);
            
            app.lsmResultsApp = lsmResults(app, c0, c1, G, b0e, b1e, ae, sig, sigB0e, sigB1e, b0eCI, b1eCI, sigAe, aeCI, aAve, sigA, n);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            app.helpApp = helpWindow;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            % delete tmp.csv
            delete tmp.csv

            % reset edit fields to default
            app.b0EditField.Value = 2;
            app.b1EditField.Value = 0.3;
            app.thetaEditField.Value = '0, 0.5, 1, 2, 3, 5';
            app.alphaEitField.Value = '1.8, 1.5, 1, 0.9, 1, 1.7';
            app.th0EditField.Value = -1;
            app.th1EditField.Value = 6;
            app.dtEditField.Value = 0.1;
            app.phi0TextArea.Value = 'exp(-thi)';
            app.phi1TextArea.Value = 'thi';

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            filter = {'saved_params\*.mldatx'};
            title = 'Save Parameters';

            [file, filePath] = uiputfile(filter,title);
            
            fullFile = strcat(filePath, file);

            b0 = app.b0EditField.Value;
            b1 = app.b1EditField.Value;
            % will later converted to array
            %th = str2num(app.thetaEditField.Value)
            %a =  str2num(app.alphaEitField.Value)
            th = app.thetaEditField.Value;
            a =  app.alphaEitField.Value;
            Th0 = app.th0EditField.Value;
            Th1 = app.th1EditField.Value;
            dt = app.dtEditField.Value;
            % user text input of functions
            f0TableIn = cell2table(app.phi0TextArea.Value);
            f1TableIn = cell2table(app.phi1TextArea.Value);

            % if user wrote several rows, conveting table to string
            % rows in table are rows of input textArea
            f0Str = Table2str(app, f0TableIn);
            f1Str = Table2str(app, f1TableIn);

            if fullFile ~= 0 
                PrintArgsToFile(app, fullFile, b0,b1,th,a, Th0,Th1,dt, f0Str, f1Str);
                % save to tmpfile
                tmpFile = 'tmp.csv';
                PrintArgsToFile(app, tmpFile, b0,b1,th,a, Th0,Th1,dt, f0Str, f1Str);
            end

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            filter = {'saved_params\*.mldatx'};
            title = 'Load Parameters';
            [file, filePath] = uigetfile(filter,title);

            fullFile = strcat(filePath, file);
            
            if fullFile ~= 0
                 ReadArgsFromFile(app, fullFile);
            end
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.b_0EditFieldLabel = uilabel(app.UIFigure);
            app.b_0EditFieldLabel.Interpreter = 'latex';
            app.b_0EditFieldLabel.Text = '$b_{0}$';
            app.b0EditField = uieditfield(app.UIFigure, 'numeric');
            app.b0EditField.Value = 2;
            app.b_1EditFieldLabel = uilabel(app.UIFigure);
            app.b_1EditFieldLabel.Interpreter = 'latex';
            app.b_1EditFieldLabel.Text = '$b_{1}$';
            app.b1EditField = uieditfield(app.UIFigure, 'numeric');
            app.b1EditField.Value = 0.3;
            app.theta_0_lowertimeboudaryLabel = uilabel(app.UIFigure);
            app.theta_0_lowertimeboudaryLabel.Interpreter = 'latex';
            app.theta_0_lowertimeboudaryLabel.Text = '$\theta_{min} \:_{(lower \: boudary):}$';
            app.th0EditField = uieditfield(app.UIFigure, 'numeric');
            app.th0EditField.Value = -1;
            app.theta_1_uppertimeboudaryLabel = uilabel(app.UIFigure);
            app.theta_1_uppertimeboudaryLabel.Interpreter = 'latex';
            app.theta_1_uppertimeboudaryLabel.Text = '$\theta_{max} \:_{(upper \: boudary):}$';
            app.th1EditField = uieditfield(app.UIFigure, 'numeric');
            app.th1EditField.Value = 6;
            app.itdtrm_stepLabel = uilabel(app.UIFigure);
            app.itdtrm_stepLabel.Interpreter = 'latex';
            app.itdtrm_stepLabel.Text = '$\it dt \: \rm _{(step):}$';
            app.dtEditField = uieditfield(app.UIFigure, 'numeric');
            app.dtEditField.Value = 0.1;
            app.LeastSquareMethodfortwoparametriccaseLabel = uilabel(app.UIFigure);
            app.LeastSquareMethodfortwoparametriccaseLabel.Text = 'Least Square Method for two parametric case ';
            app.theta_6valuesvectorEditField_2Label = uilabel(app.UIFigure);
            app.theta_6valuesvectorEditField_2Label.Interpreter = 'latex';
            app.theta_6valuesvectorEditField_2Label.Text = '$\theta \:_{(6\:values \: vector):}$';
            app.thetaEditField = uieditfield(app.UIFigure, 'text');
            app.thetaEditField.Value = '0, 0.5, 1, 2, 3, 5';
            app.alpha_6valuesvectorEditField_2Label = uilabel(app.UIFigure);
            app.alpha_6valuesvectorEditField_2Label.Interpreter = 'latex';
            app.alpha_6valuesvectorEditField_2Label.Text = '$\alpha \:_{(6\:values \: vector):}$';
            app.alphaEitField = uieditfield(app.UIFigure, 'text');
            app.alphaEitField.Value = '1.8, 1.5, 1, 0.9, 1, 1.7';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.phi_0theta_writethetaassymbolitthiTextAreaLabel = uilabel(app.UIFigure);
            app.phi_0theta_writethetaassymbolitthiTextAreaLabel.Interpreter = 'latex';
            app.phi_0theta_writethetaassymbolitthiTextAreaLabel.Text = '$\phi_{0}(\theta)\\ _{ \:(write\: \theta \:as \:symbol\: \it \:thi)}$';
            app.phi0TextArea = uitextarea(app.UIFigure);
            app.phi0TextArea.Value = {'exp(-thi)'};
            app.phi_1theta_writethetaassymbolitthiLabel = uilabel(app.UIFigure);
            app.phi_1theta_writethetaassymbolitthiLabel.Interpreter = 'latex';
            app.phi_1theta_writethetaassymbolitthiLabel.Text = '$\phi_{1}(\theta)\\ _{ \:(write\: \theta \:as \:symbol\: \it \:thi)}$';
            app.phi1TextArea = uitextarea(app.UIFigure);
            app.phi1TextArea.Value = {'thi'};
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lsmApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end