classdef lsmResults < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LSMResultsUIFigure     matlab.ui.Figure
        sigma_aiTextArea       matlab.ui.control.TextArea
        sigma_aiLabel          matlab.ui.control.Label
        aeiTextArea            matlab.ui.control.TextArea
        a_ipmLabel             matlab.ui.control.Label
        etaTextArea            matlab.ui.control.TextArea
        aAveTextArea           matlab.ui.control.TextArea
        eta_Label              matlab.ui.control.Label
        aAveLabel              matlab.ui.control.Label
        sigma_aTextArea        matlab.ui.control.TextArea
        sigma_aLabel           matlab.ui.control.Label
        b1pmtextArea           matlab.ui.control.TextArea
        b_1pmLabel             matlab.ui.control.Label
        b0pmtextArea           matlab.ui.control.TextArea
        b_0pmLabel             matlab.ui.control.Label
        sigma_b1TextArea       matlab.ui.control.TextArea
        sigma_b1Label          matlab.ui.control.Label
        sigma_b0TextArea       matlab.ui.control.TextArea
        sigma_b0Label          matlab.ui.control.Label
        sigma_epsilonTextArea  matlab.ui.control.TextArea
        sigma_epsilonLabel     matlab.ui.control.Label
        aeTextArea             matlab.ui.control.TextArea
        hatalphathetaLabel     matlab.ui.control.Label
        b1textArea             matlab.ui.control.TextArea
        b0textArea             matlab.ui.control.TextArea
        hatb_1Label            matlab.ui.control.Label
        hatb_0Label            matlab.ui.control.Label
        GtextArea              matlab.ui.control.TextArea
        GmatrixLabel           matlab.ui.control.Label
        c1textArea             matlab.ui.control.TextArea
        c0textArea             matlab.ui.control.TextArea
        c_1Label               matlab.ui.control.Label
        c_0Label               matlab.ui.control.Label
    end

    
    properties (Access = private)
        lsmApp; % caller lsmApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, lsmApp, c0, c1, G, b0e, b1e, ae, sig, sigB0e, sigB1e, b0eCI, b1eCI, sigAe, aeCI, aAve, sigA, n)
            app.lsmApp = lsmApp;

            app.c0textArea.Value = num2str(c0, 4);
            app.c1textArea.Value = num2str(c1, 4);

            app.GtextArea.Value = strcat(mat2str(G(1,1), 4), " ; " ,mat2str(G(1,2), 4), ...
                {newline}, mat2str(G(2,1), 4), " ; " ,mat2str(G(2,2), 4));

            app.b0textArea.Value = num2str(b0e, 4);
            app.b1textArea.Value = num2str(b1e, 4);
            
            alphaStr = strcat("1:   ", num2str(ae(1), 4));
            for i = 2:length(ae)             
                alphaStr = strcat(alphaStr, {newline}, num2str(i), ":   ", num2str(ae(i), 4));
            end
            app.aeTextArea.Value = alphaStr;
           
            app.sigma_epsilonTextArea.Value = num2str(sig,4);
            app.sigma_b0TextArea.Value = num2str(sigB0e,4);
            app.sigma_b1TextArea.Value = num2str(sigB1e,4);
            
            app.b0pmtextArea.Value = strcat("(", mat2str(b0eCI(1),4), " ; ", mat2str(b0eCI(2),4), ")");
            app.b1pmtextArea.Value = strcat("(", mat2str(b1eCI(1),4), " ; ", mat2str(b1eCI(2),4), ")");
            
            % mark b0 and b1 if confidence interval contains Zero
            if (discretize(0, b0eCI) == 1)
                app.b0textArea.BackgroundColor = '#ff9999';
                app.b0pmtextArea.BackgroundColor = '#ff9999';
            end

            if (discretize(0, b1eCI) == 1)
                app.b1textArea.BackgroundColor = '#ff9999';
                app.b1pmtextArea.BackgroundColor = '#ff9999';
            end

            % sigma a^
            sigAiStr = strcat("1:   ", num2str(sigAe(1),4));
            for i = 2:length(sigAe)
                sigAiStr = strcat(sigAiStr, {newline}, num2str(i), ":   ", num2str(sigAe(i), 4));
            end
            app.sigma_aiTextArea.Value = sigAiStr;

            % alpha confidence intervals
            aeCIStr = strcat("1:    ", "(",  num2str(aeCI(1,1),4), " ; ", num2str(aeCI(1,2),4), ") ");
            for i = 2:length(aeCI)
                aeCIStr = strcat(aeCIStr, {newline}, num2str(i), ":    ", "(",  num2str(aeCI(i,1),4), " ; ", num2str(aeCI(i,2),4), ") ");
            end
            app.aeiTextArea.Value = aeCIStr;

            
            app.aAveTextArea.Value = num2str(aAve,4);

            app.sigma_aTextArea.Value = num2str(sigA,4);

            app.etaTextArea.Value = num2str(n,4);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LSMResultsUIFigure = uifigure('Visible', 'off');
            app.LSMResultsUIFigure.Name = 'LSM Results';
            app.c_0Label = uilabel(app.LSMResultsUIFigure);
            app.c_0Label.Interpreter = 'latex';
            app.c_0Label.Text = '$c_{0}$';
            app.c_1Label = uilabel(app.LSMResultsUIFigure);
            app.c_1Label.Interpreter = 'latex';
            app.c_1Label.Text = '$c_{1}$';
            app.c0textArea = uitextarea(app.LSMResultsUIFigure);
            app.c0textArea.Editable = 'off';
            app.c1textArea = uitextarea(app.LSMResultsUIFigure);
            app.c1textArea.Editable = 'off';
            app.GmatrixLabel = uilabel(app.LSMResultsUIFigure);
            app.GmatrixLabel.Interpreter = 'latex';
            app.GmatrixLabel.Text = '$G = \left(\begin{array}{cc} g_{11} & g_{12}\\ {g_{21} & g_{22} \end{array}\right)$';
            app.GtextArea = uitextarea(app.LSMResultsUIFigure);
            app.GtextArea.Editable = 'off';
            app.hatb_0Label = uilabel(app.LSMResultsUIFigure);
            app.hatb_0Label.Interpreter = 'latex';
            app.hatb_0Label.Text = '$\hat b_{0}$';
            app.hatb_1Label = uilabel(app.LSMResultsUIFigure);
            app.hatb_1Label.Interpreter = 'latex';
            app.hatb_1Label.Text = '$\hat b_{1}$';
            app.b0textArea = uitextarea(app.LSMResultsUIFigure);
            app.b0textArea.Editable = 'off';
            app.b1textArea = uitextarea(app.LSMResultsUIFigure);
            app.b1textArea.Editable = 'off';
            app.hatalphathetaLabel = uilabel(app.LSMResultsUIFigure);
            app.hatalphathetaLabel.Interpreter = 'latex';
            app.hatalphathetaLabel.Text = '$\hat{\alpha}({\theta})$';
            app.aeTextArea = uitextarea(app.LSMResultsUIFigure);
            app.aeTextArea.Editable = 'off';
            app.sigma_epsilonLabel = uilabel(app.LSMResultsUIFigure);
            app.sigma_epsilonLabel.Interpreter = 'latex';
            app.sigma_epsilonLabel.Text = '$\hat \sigma_{\epsilon}$';
            app.sigma_epsilonTextArea = uitextarea(app.LSMResultsUIFigure);
            app.sigma_epsilonTextArea.Editable = 'off';
            app.sigma_b0Label = uilabel(app.LSMResultsUIFigure);
            app.sigma_b0Label.Interpreter = 'latex';
            app.sigma_b0Label.Text = '$\hat \sigma_{\hat b_{0}}$';
            app.sigma_b0TextArea = uitextarea(app.LSMResultsUIFigure);
            app.sigma_b0TextArea.Editable = 'off';
            app.sigma_b1Label = uilabel(app.LSMResultsUIFigure);
            app.sigma_b1Label.Interpreter = 'latex';
            app.sigma_b1Label.Text = '$\hat \sigma_{\hat b_{1}}$';
            app.sigma_b1TextArea = uitextarea(app.LSMResultsUIFigure);
            app.sigma_b1TextArea.Editable = 'off';
            app.b_0pmLabel = uilabel(app.LSMResultsUIFigure);
            app.b_0pmLabel.Interpreter = 'latex';
            app.b_0pmLabel.Text = '$\hat b_{0} \pm 2 \hat  \sigma_{\hat b_{0}} $';
            app.b0pmtextArea = uitextarea(app.LSMResultsUIFigure);
            app.b0pmtextArea.Editable = 'off';
            app.b_1pmLabel = uilabel(app.LSMResultsUIFigure);
            app.b_1pmLabel.Interpreter = 'latex';
            app.b_1pmLabel.Text = '$\hat b_{1} \pm  2 \hat \sigma_{\hat b_{1}} $';
            app.b1pmtextArea = uitextarea(app.LSMResultsUIFigure);
            app.b1pmtextArea.Editable = 'off';
            app.sigma_aLabel = uilabel(app.LSMResultsUIFigure);
            app.sigma_aLabel.Interpreter = 'latex';
            app.sigma_aLabel.Text = '$\hat \sigma_{\alpha}$';
            app.sigma_aTextArea = uitextarea(app.LSMResultsUIFigure);
            app.sigma_aTextArea.Editable = 'off';
            app.aAveLabel = uilabel(app.LSMResultsUIFigure);
            app.aAveLabel.Interpreter = 'latex';
            app.aAveLabel.Text = '$\bar \alpha$';
            app.eta_Label = uilabel(app.LSMResultsUIFigure);
            app.eta_Label.Interpreter = 'latex';
            app.eta_Label.Text = '$\eta$';
            app.aAveTextArea = uitextarea(app.LSMResultsUIFigure);
            app.aAveTextArea.Editable = 'off';
            app.etaTextArea = uitextarea(app.LSMResultsUIFigure);
            app.etaTextArea.Editable = 'off';
            app.a_ipmLabel = uilabel(app.LSMResultsUIFigure);
            app.a_ipmLabel.Interpreter = 'latex';
            app.a_ipmLabel.Text = '$\hat a_{i} \pm 2 \hat  \sigma_{\hat \alpha_{i}} $';
            app.aeiTextArea = uitextarea(app.LSMResultsUIFigure);
            app.aeiTextArea.Editable = 'off';
            app.sigma_aiLabel = uilabel(app.LSMResultsUIFigure);
            app.sigma_aiLabel.Interpreter = 'latex';
            app.sigma_aiLabel.Text = '$\hat \sigma_{\hat \alpha_{i}}$';
            app.sigma_aiTextArea = uitextarea(app.LSMResultsUIFigure);
            app.sigma_aiTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.LSMResultsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lsmResults(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.LSMResultsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.LSMResultsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LSMResultsUIFigure)
        end
    end
end